classdef DMCurrent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NOButton_3           matlab.ui.control.Button
        NOButton_2           matlab.ui.control.Button
        YESButton_3          matlab.ui.control.Button
        YESButton_2          matlab.ui.control.Button
        NOButton             matlab.ui.control.Button
        YESButton            matlab.ui.control.Button
        AreyousureLabel      matlab.ui.control.Label
        SETButton_3          matlab.ui.control.Button
        SETButton_2          matlab.ui.control.Button
        SETButton            matlab.ui.control.Button
        RampTimeEditField    matlab.ui.control.NumericEditField
        RamptimeLabel        matlab.ui.control.Label
        CH3EditField_2       matlab.ui.control.NumericEditField
        CH3EditField_2Label  matlab.ui.control.Label
        CH2EditField_2       matlab.ui.control.NumericEditField
        CH2EditField_2Label  matlab.ui.control.Label
        CH1EditField_2       matlab.ui.control.NumericEditField
        CH1EditField_2Label  matlab.ui.control.Label
        SetcurrentLabel      matlab.ui.control.Label
        ReadcurrentLabel     matlab.ui.control.Label
        ClearButton          matlab.ui.control.Button
        StopButton           matlab.ui.control.Button
        StartButton          matlab.ui.control.Button
        CH3EditField         matlab.ui.control.NumericEditField
        CH3EditFieldLabel    matlab.ui.control.Label
        CH2EditField         matlab.ui.control.NumericEditField
        CH2EditFieldLabel    matlab.ui.control.Label
        CH1EditField         matlab.ui.control.NumericEditField
        CH1EditFieldLabel    matlab.ui.control.Label
        COMEditField         matlab.ui.control.NumericEditField
        COMEditFieldLabel    matlab.ui.control.Label
        DisConnectButton     matlab.ui.control.Button
        ConnectButton        matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        s1 % Description
        mytimer
        timelist % Description
        currentlist % Description
        time % Description
    end
    
    methods (Access = public)
        
        function readcurrent(app)
            try
                app.time=app.time+1;
                flush(app.s1);
                currentformat='[+-]?\d+\.\d+';

                writeline(app.s1,'!chk;1');
                currentreply1=readline(app.s1);
                writeline(app.s1,'!chk;2');
                currentreply2=readline(app.s1);
                writeline(app.s1,'!chk;3');
                currentreply3=readline(app.s1);

                current1=str2double(regexp(currentreply1, currentformat, 'match'));
                current2=str2double(regexp(currentreply2, currentformat, 'match'));
                current3=str2double(regexp(currentreply3, currentformat, 'match'));
                app.CH1EditField.Value=current1;
                app.CH2EditField.Value=current2;
                app.CH3EditField.Value=current3;
                
                app.timelist=[app.timelist app.time];
                app.currentlist=[app.currentlist current2];
                plot(app.UIAxes,app.timelist,app.currentlist);
                
            catch
                disp('Read current ERROR!');
            end
        end
        
        function setcurrent(app,ch_index)
            flush(app.s1);
            currentformat='[+-]?\d+\.\d+';
            writeline(app.s1,['!chk;',num2str(ch_index)]);
            currentreply=readline(app.s1);
            initial_current=str2double(regexp(currentreply, currentformat, 'match'));
            ramptime=app.RampTimeEditField.Value;
            rampsteps=ramptime/0.5; % set current per 0.5s
            eval(['final_current=app.CH',num2str(ch_index),'EditField_2.Value;']);
            disp(['Start ramping CH',num2str(ch_index),' from ',num2str(initial_current),'uA ','to ',num2str(final_current),'uA']);
            for i=1:round(rampsteps)
                set_current=(final_current-initial_current)*i/rampsteps+initial_current;
                writeline(app.s1,['!set;',num2str(ch_index),';40mA;',num2str(set_current)]);
                try
                readline(app.s1);
                catch
                end
                pause(0.5);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.timelist=[];
            app.currentlist=[];
            app.time=0;
            try


            comport=['COM',num2str(app.COMEditField.Value)];
            app.s1=serialport(comport,115200);
            app.s1.Timeout=1;
            disp('Connected!');
            app.ConnectButton.BackgroundColor=[0 1 0];
            app.DisConnectButton.BackgroundColor=[0.96 0.96 0.96];
            writeline(app.s1,'!idn');
            identity=readline(app.s1);
            identity=identity{1}(1,:);
            disp(['Identity information:',identity]);
            
            catch
                disp('Connection error!')
                app.ConnectButton.BackgroundColor=[0.96 0.96 0.96];
                app.DisConnectButton.BackgroundColor=[1 0 0];
            end
        end

        % Button pushed function: DisConnectButton
        function DisConnectButtonPushed(app, event)
            try
                delete(app.s1);
                disp('Disconnected!');
                app.ConnectButton.BackgroundColor=[0.96 0.96 0.96];
                app.DisConnectButton.BackgroundColor=[1 0 0];
            catch
                disp('SerialPort does not exist in matlab!');
                app.ConnectButton.BackgroundColor=[0.96 0.96 0.96];
                app.DisConnectButton.BackgroundColor=[1 0 0];
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.mytimer=timer('Period',1,'ExecutionMode','fixedSpacing');
            app.mytimer.TimerFcn = {@(~,~)app.readcurrent};
            start(app.mytimer);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
            stop(app.mytimer);
            stop(timerfind);
            delete(app.mytimer);
            catch
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.currentlist=[];
            app.timelist=[];
            app.time=0;
        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)
            app.AreyousureLabel.Visible="on";
            app.AreyousureLabel.Text=["Are you sure?","Current is ",['[',num2str(app.CH1EditField.Value),' ',num2str(app.CH2EditField.Value),' ',num2str(app.CH3EditField.Value),']uA'], "right now!"];
            app.YESButton.Visible="on";
            app.NOButton.Visible="on";
            
        end

        % Button pushed function: SETButton_2
        function SETButton_2Pushed(app, event)
            app.AreyousureLabel.Visible="on";
            app.AreyousureLabel.Text=["Are you sure?","Current is ",['[',num2str(app.CH1EditField.Value),' ',num2str(app.CH2EditField.Value),' ',num2str(app.CH3EditField.Value),']uA'], "right now!"];
            app.YESButton_2.Visible="on";
            app.NOButton_2.Visible="on";
            
        end

        % Button pushed function: SETButton_3
        function SETButton_3Pushed(app, event)
            app.AreyousureLabel.Visible="on";
            app.AreyousureLabel.Text=["Are you sure?","Current is ",['[',num2str(app.CH1EditField.Value),' ',num2str(app.CH2EditField.Value),' ',num2str(app.CH3EditField.Value),']uA'], "right now!"];
            app.YESButton_3.Visible="on";
            app.NOButton_3.Visible="on";
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            app.AreyousureLabel.Visible="off";
            app.YESButton.Visible="off";
            app.NOButton.Visible="off";
            try
            setcurrent(app,1);
            disp('Ramping finished!');
            catch
                disp('Set current failed!')
            end
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            app.AreyousureLabel.Visible="off";
            app.YESButton.Visible="off";
            app.NOButton.Visible="off";
        end

        % Button pushed function: YESButton_2
        function YESButton_2Pushed(app, event)
            app.AreyousureLabel.Visible="off";
            app.YESButton_2.Visible="off";
            app.NOButton_2.Visible="off";
            try
            setcurrent(app,2);
            disp('Ramping finished!');
            catch
                disp('Set current failed!')
            end
        end

        % Button pushed function: NOButton_2
        function NOButton_2Pushed(app, event)
            app.AreyousureLabel.Visible="off";
            app.YESButton_2.Visible="off";
            app.NOButton_2.Visible="off";
        end

        % Button pushed function: YESButton_3
        function YESButton_3Pushed(app, event)
            app.AreyousureLabel.Visible="off";
            app.YESButton_3.Visible="off";
            app.NOButton_3.Visible="off";
            try
            setcurrent(app,3);
            disp('Ramping finished!');
            catch
                disp('Set current failed!')
            end
        end

        % Button pushed function: NOButton_3
        function NOButton_3Pushed(app, event)
             app.AreyousureLabel.Visible="off";
            app.YESButton_3.Visible="off";
            app.NOButton_3.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH2 Current v.s. T')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Current uA')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 Inf];
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisConnectButton = uibutton(app.UIFigure, 'push');
            app.DisConnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisConnectButtonPushed, true);
            app.DisConnectButton.Text = 'DisConnect';
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'numeric');
            app.COMEditField.Value = 4;
            app.CH1EditFieldLabel = uilabel(app.UIFigure);
            app.CH1EditFieldLabel.Text = 'CH1 ';
            app.CH1EditField = uieditfield(app.UIFigure, 'numeric');
            app.CH1EditField.ValueDisplayFormat = '%11.2f uA';
            app.CH2EditFieldLabel = uilabel(app.UIFigure);
            app.CH2EditFieldLabel.Text = 'CH2';
            app.CH2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CH2EditField.ValueDisplayFormat = '%11.2f uA';
            app.CH3EditFieldLabel = uilabel(app.UIFigure);
            app.CH3EditFieldLabel.Text = 'CH3';
            app.CH3EditField = uieditfield(app.UIFigure, 'numeric');
            app.CH3EditField.ValueDisplayFormat = '%11.2f uA';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ReadcurrentLabel = uilabel(app.UIFigure);
            app.ReadcurrentLabel.Text = 'Read current';
            app.SetcurrentLabel = uilabel(app.UIFigure);
            app.SetcurrentLabel.Text = 'Set current';
            app.CH1EditField_2Label = uilabel(app.UIFigure);
            app.CH1EditField_2Label.Text = 'CH1 ';
            app.CH1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CH1EditField_2.Limits = [-40000 40000];
            app.CH1EditField_2.ValueDisplayFormat = '%11.2f uA';
            app.CH2EditField_2Label = uilabel(app.UIFigure);
            app.CH2EditField_2Label.Text = 'CH2';
            app.CH2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CH2EditField_2.Limits = [-40000 40000];
            app.CH2EditField_2.ValueDisplayFormat = '%11.2f uA';
            app.CH3EditField_2Label = uilabel(app.UIFigure);
            app.CH3EditField_2Label.Text = 'CH3';
            app.CH3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CH3EditField_2.Limits = [-40000 40000];
            app.CH3EditField_2.ValueDisplayFormat = '%11.2f uA';
            app.RamptimeLabel = uilabel(app.UIFigure);
            app.RamptimeLabel.Text = {'Ramp'; 'Time'};
            app.RampTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RampTimeEditField.ValueDisplayFormat = '%11d s';
            app.RampTimeEditField.Value = 100;
            app.SETButton = uibutton(app.UIFigure, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = 'SET';
            app.SETButton_2 = uibutton(app.UIFigure, 'push');
            app.SETButton_2.ButtonPushedFcn = createCallbackFcn(app, @SETButton_2Pushed, true);
            app.SETButton_2.Text = 'SET';
            app.SETButton_3 = uibutton(app.UIFigure, 'push');
            app.SETButton_3.ButtonPushedFcn = createCallbackFcn(app, @SETButton_3Pushed, true);
            app.SETButton_3.Text = 'SET';
            app.AreyousureLabel = uilabel(app.UIFigure);
            app.AreyousureLabel.Visible = 'off';
            app.AreyousureLabel.Text = 'Are you sure?';
            app.YESButton = uibutton(app.UIFigure, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Visible = 'off';
            app.YESButton.Text = 'YES';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Visible = 'off';
            app.NOButton.Text = 'NO';
            app.YESButton_2 = uibutton(app.UIFigure, 'push');
            app.YESButton_2.ButtonPushedFcn = createCallbackFcn(app, @YESButton_2Pushed, true);
            app.YESButton_2.Visible = 'off';
            app.YESButton_2.Text = 'YES';
            app.YESButton_3 = uibutton(app.UIFigure, 'push');
            app.YESButton_3.ButtonPushedFcn = createCallbackFcn(app, @YESButton_3Pushed, true);
            app.YESButton_3.Visible = 'off';
            app.YESButton_3.Text = 'YES';
            app.NOButton_2 = uibutton(app.UIFigure, 'push');
            app.NOButton_2.ButtonPushedFcn = createCallbackFcn(app, @NOButton_2Pushed, true);
            app.NOButton_2.Visible = 'off';
            app.NOButton_2.Text = 'NO';
            app.NOButton_3 = uibutton(app.UIFigure, 'push');
            app.NOButton_3.ButtonPushedFcn = createCallbackFcn(app, @NOButton_3Pushed, true);
            app.NOButton_3.Visible = 'off';
            app.NOButton_3.Text = 'NO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DMCurrent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end