classdef BackgroundCamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ImageLabel            matlab.ui.control.Label
        YRangeEditFieldLabel  matlab.ui.control.Label
        YRangeEditField       matlab.ui.control.NumericEditField
        XRangeEditFieldLabel  matlab.ui.control.Label
        XRangeEditField       matlab.ui.control.NumericEditField
        XCenterLabel          matlab.ui.control.Label
        Center_left           matlab.ui.control.NumericEditField
        YCenterLabel          matlab.ui.control.Label
        Center_top            matlab.ui.control.NumericEditField
        ExposureLabel         matlab.ui.control.Label
        ExposureTime          matlab.ui.control.NumericEditField
        NumberLabel           matlab.ui.control.Label
        total_image_number    matlab.ui.control.NumericEditField
        StartButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Text = '运行中';
            app.StartButton.BackgroundColor=[0 1 0.5];

            
            background_path=['E:\Data\',datestr(now,'yyyy-mm-dd'),'\Background\'];
            try
                mkdir(background_path);
            catch 
                
            end
                    %相机初始化，配置参数
                    %%
                    [rc] = AT_InitialiseLibrary();%获取库
                    AT_CheckWarning(rc);
                    [rc,hndl] = AT_Open(0);
                    disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera external trigger mode']);
                    % 设置相机参数
                    AT_SetFloat(hndl,'ExposureTime',app.ExposureTime.Value);%设置曝光时间
                    AT_SetEnumString(hndl,'PixelReadoutRate','Normal');%读出率，normal 200MHz
                    AT_SetBool(hndl,'Overlap',1);%overapmode 1
                    AT_SetEnumString(hndl,'ElectronicShutteringMode','Global');    %设置为global shutter               
                    AT_SetEnumString(hndl,'SimplePreAmpGainControl','16-bit (low noise & high well capacity)'); %设置成16位
                    AT_SetEnumString(hndl,'PixelEncoding','Mono16');
                    AT_CheckWarning(rc);
                    AT_SetInt(hndl,'AOIHeight',app.YRangeEditField.Value);    %设置高度
                    AT_SetInt(hndl,'AOIWidth',app.XRangeEditField.Value);    %设置宽度
                    try
                    AT_SetInt(hndl,'AOITop',app.Center_top.Value-floor(app.YRangeEditField.Value/2));    %设置上边界
                    AT_SetInt(hndl,'AOILeft',app.Center_left.Value-floor(app.XRangeEditField.Value/2)); %设置左边界
                    catch
                    disp('Center Settings Error');
                    end    
                    % 获取相片大小，便于创建对应图片矩阵
                    [rc,imagesize] = AT_GetInt(hndl,'imagesizeBytes');
                    AT_CheckWarning(rc);
                    [rc,height] = AT_GetInt(hndl,'AOIheight');
                    AT_CheckWarning(rc);
                    [rc,width] = AT_GetInt(hndl,'AOIwidth');
                    AT_CheckWarning(rc);
                    [rc,stride] = AT_GetInt(hndl,'AOIstride');
                    AT_CheckWarning(rc);
                    AT_SetFloat(hndl,'ExternalTriggerDelay',0);
                    AT_SetEnumString(hndl,'CycleMode','Continuous');
                    AT_SetEnumString(hndl,'TriggerMode','External');
                    disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera initialized']);
                    [rc,real_exposure]=AT_GetFloat(hndl,'ExposureTime');%真实曝光时间
                    AT_CheckWarning(rc);
                    app.ExposureTime.Value=real_exposure;
                    app.ExposureTime.ValueDisplayFormat='%.3f s';
                   
                    [rc] = AT_QueueBuffer(hndl,imagesize);
                    AT_CheckWarning(rc);
                    disp(' ');
                    disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Waiting for trigger']);
                    [rc] = AT_Command(hndl,'AcquisitionStart'); %这个命令让采集开始
                    %AT_CheckWarning(rc);
                    
                    
                    for bgnumber=1:1:app.total_image_number.Value

                         buf2 = zeros(width,height);%创建一个空buffer，用来将相机的图片收过
                        
                         %h=imagesc(buf2); %在matlab里显示图片
                        filename=[background_path,num2str(bgnumber),'.tif',];%将照片存储到相机图像存储路径
                        AT_QueueBuffer(hndl,imagesize);
                        [~,buf] = AT_WaitBuffer(hndl,1000000); %在Timeout时间里等相机送过来的图片，如果超出这个timeout时间还没有图片，则自动报错退出
                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);%将Mono16图片变成个矩阵
                  
                        %set(h,'CData',buf2);   %将matlab显示的图片数据刷新，下一步是刷新图片
                        %drawnow;   %刷新图片
                        imwrite(buf2, filename);%把图片保存到本地
                        disp(['Image ',num2str(bgnumber),' saved']);
                    end


                %结束连接
                disp(' ');
                disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Acquisition complete - trigger mode']);
                [rc] = AT_Command(hndl,'AcquisitionStop');
                %AT_CheckWarning(rc);
                [rc] = AT_Flush(hndl);
                AT_CheckWarning(rc);
                [rc] = AT_Close(hndl);
                AT_CheckWarning(rc);
                [rc] = AT_FinaliseLibrary();
                AT_CheckWarning(rc);
                disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera shutdown']);
                app.StartButton.Text = '再次开始';
                app.StartButton.BackgroundColor=[1 0 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.YRangeEditFieldLabel = uilabel(app.UIFigure);
            app.YRangeEditFieldLabel.Text = 'Y Range';
            app.YRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.YRangeEditField.Limits = [1 2160];
            app.YRangeEditField.Value = 200;
            app.XRangeEditFieldLabel = uilabel(app.UIFigure);
            app.XRangeEditFieldLabel.Text = 'X Range';
            app.XRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.XRangeEditField.Limits = [1 2560];
            app.XRangeEditField.Value = 200;
            app.XCenterLabel = uilabel(app.UIFigure);
            app.XCenterLabel.Text = 'X Center';
            app.Center_left = uieditfield(app.UIFigure, 'numeric');
            app.Center_left.Limits = [1 2560];
            app.Center_left.Value = 1300;
            app.YCenterLabel = uilabel(app.UIFigure);
            app.YCenterLabel.Text = 'Y Center';
            app.Center_top = uieditfield(app.UIFigure, 'numeric');
            app.Center_top.Limits = [1 2160];
            app.Center_top.Value = 800;
            app.ExposureLabel = uilabel(app.UIFigure);
            app.ExposureLabel.Text = 'Exposure';
            app.ExposureTime = uieditfield(app.UIFigure, 'numeric');
            app.ExposureTime.Limits = [0 Inf];
            app.ExposureTime.ValueDisplayFormat = '%.3f s';
            app.ExposureTime.Value = 0.002;
            app.NumberLabel = uilabel(app.UIFigure);
            app.NumberLabel.Text = 'Number';
            app.total_image_number = uieditfield(app.UIFigure, 'numeric');
            app.total_image_number.Value = 1;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackgroundCamera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end