classdef CameraSoftware_20260323 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveFigSwitch                  matlab.ui.control.Switch
        SaveFigSwitchLabel             matlab.ui.control.Label
        Image_ratio                    matlab.ui.control.DropDown
        Label_6                        matlab.ui.control.Label
        ShutterModeButtonGroup         matlab.ui.container.ButtonGroup
        GlobalShutterButton            matlab.ui.control.RadioButton
        RollingShutterButton           matlab.ui.control.RadioButton
        lightphotoediting              matlab.ui.control.NumericEditField
        LightNumberLabel               matlab.ui.control.Label
        FringeRemovalSwitch            matlab.ui.control.Switch
        FringeRemovalSwitchLabel       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        SaveSettingsdirEditField       matlab.ui.control.EditField
        SaveSettingsdirEditFieldLabel  matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        LoadSettingsdirEditField       matlab.ui.control.EditField
        LoadSettingsdirEditFieldLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        AdditionEditField              matlab.ui.control.NumericEditField
        AdditionEditFieldLabel         matlab.ui.control.Label
        scale                          matlab.ui.control.NumericEditField
        ODscaleEditFieldLabel          matlab.ui.control.Label
        Fit2DSwitch                    matlab.ui.control.Switch
        Fit2DSwitchLabel               matlab.ui.control.Label
        ROILabel_2                     matlab.ui.control.Label
        ImageLabel                     matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        MaxPixelTextArea               matlab.ui.control.TextArea
        MaxPixelTextAreaLabel          matlab.ui.control.Label
        Center_top                     matlab.ui.control.NumericEditField
        YCenterLabel                   matlab.ui.control.Label
        Center_left                    matlab.ui.control.NumericEditField
        XCenterLabel                   matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        XminEditField                  matlab.ui.control.NumericEditField
        XminEditFieldLabel             matlab.ui.control.Label
        YmaxEditField                  matlab.ui.control.NumericEditField
        YmaxEditFieldLabel             matlab.ui.control.Label
        YminEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        XmaxEditField                  matlab.ui.control.NumericEditField
        XmaxEditFieldLabel             matlab.ui.control.Label
        process_path                   matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        atomphotoediting               matlab.ui.control.NumericEditField
        AtomNumberLabel                matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Label_3                        matlab.ui.control.Label
        stop                           matlab.ui.control.Button
        camera_path                    matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        XRangeEditField                matlab.ui.control.NumericEditField
        XRangeEditFieldLabel           matlab.ui.control.Label
        YRangeEditField                matlab.ui.control.NumericEditField
        YRangeEditFieldLabel           matlab.ui.control.Label
        ExposureTime                   matlab.ui.control.NumericEditField
        ExposureLabel                  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Start                          matlab.ui.control.Button
        image_30                       matlab.ui.control.UIAxes
        image_29                       matlab.ui.control.UIAxes
        image_28                       matlab.ui.control.UIAxes
        image_27                       matlab.ui.control.UIAxes
        image_26                       matlab.ui.control.UIAxes
        image_25                       matlab.ui.control.UIAxes
        image_24                       matlab.ui.control.UIAxes
        image_23                       matlab.ui.control.UIAxes
        image_22                       matlab.ui.control.UIAxes
        image_21                       matlab.ui.control.UIAxes
        image_20                       matlab.ui.control.UIAxes
        image_19                       matlab.ui.control.UIAxes
        image_18                       matlab.ui.control.UIAxes
        image_17                       matlab.ui.control.UIAxes
        image_16                       matlab.ui.control.UIAxes
        image_15                       matlab.ui.control.UIAxes
        image_14                       matlab.ui.control.UIAxes
        image_13                       matlab.ui.control.UIAxes
        image_12                       matlab.ui.control.UIAxes
        image_11                       matlab.ui.control.UIAxes
        image_10                       matlab.ui.control.UIAxes
        image_9                        matlab.ui.control.UIAxes
        image_8                        matlab.ui.control.UIAxes
        image_7                        matlab.ui.control.UIAxes
        image_6                        matlab.ui.control.UIAxes
        image_5                        matlab.ui.control.UIAxes
        image_4                        matlab.ui.control.UIAxes
        image_3                        matlab.ui.control.UIAxes
        image_2                        matlab.ui.control.UIAxes
        image_1                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        switchstatus% 开始还是停止，用于控制相机停止拍照
        maxpixel % 相片最大像素值
        stopfit %停止拟合
    end


    methods (Access = private)

        function showimage_raw(app,imageIn,Axes_In)
            %用于显示相机拍摄原始照片
            %Set the image size to be 160*160
            imshow(imageIn,'Border','tight','initialmagnification','fit','Colormap',gray,'Parent',Axes_In);
            Axes_In.CLim=[-0.1 app.maxpixel];
            %Axes_In.XLim=[0 160];
            %Axes_In.YLim=[0 160];
        end
        function showimage_OD(app,imageIn,Axes_In)
            %用于显示计算出的OD照片
            %Set the image size to be 160*160
            imshow(imageIn,'Border','tight','initialmagnification','fit','Colormap',jet,'Parent',Axes_In);
            Axes_In.CLim=[-0.1,app.scale.Value];
            %Axes_In.XLim=[0 160];
            %Axes_In.YLim=[0 160];
        end
        function showimage_fit(app,imageIn,Axes_In)
            %Set the image size to be 160*160
            imshow(imageIn,'Border','tight','initialmagnification','fit','Colormap',jet,'Parent',Axes_In);
            Axes_In.CLim=[-0.1,app.scale.Value];
            %Axes_In.XLim=[0 160];
            %Axes_In.YLim=[0 160];

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Start
        function StartButtonPushed(app, event)

            app.Start.Text = '待命';
            app.Start.BackgroundColor=[0 1 0.5];

            %% 定义全局变量以及部分局部变量赋值
            global imagenumber;
            global totalnumber;%每次触发拍摄几张照片
            global totalswitch;
            global dataprocess;
            global Pulse_number_list;
            global process_dir; %处理后的照片以及OD存储位置
            global camera_origin_dir; %原始照片存储位置

            %全局变量初始化
            camera_origin_dir=app.camera_path.Value;
            process_dir=[app.process_path.Value,'\',datestr(now,'yyyy-mm-dd'),'\'];
            dataprocess=0;
            %dataprocess=1;%调试处理算法用，一般不运行；
            atomphoto=app.atomphotoediting.Value;
            lightphoto=app.lightphotoediting.Value;
            totalnumber=atomphoto+lightphoto+1;
            %局部变量赋值
            app.Lamp.Color=[1,1,0];
            app.switchstatus=1;

            %% 相机初始化
            [rc] = AT_InitialiseLibrary();%调用库
            AT_CheckWarning(rc);
            [rc,hndl] = AT_Open(0);
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera connected']);
            % 设置相机参数
            % AT_SetFloat(hndl,'ExposureTime',app.ExposureTime.Value/1000);%曝光时间
            AT_SetEnumString(hndl,'SimplePreAmpGainControl','16-bit (low noise & high well capacity)');%选择16bit模式
            AT_SetEnumString(hndl,'PixelEncoding','Mono16');
            AT_SetBool(hndl,'SpuriousNoiseFilter',1);
            AT_CheckWarning(rc);
            AT_SetInt(hndl,'AOIheight',app.YRangeEditField.Value);    %设置分辨率（ROI）
            AT_SetInt(hndl,'AOIwidth',app.XRangeEditField.Value);
            % 获取相片大小，便于创建对应图片矩阵
            [rc,imagesize] = AT_GetInt(hndl,'imagesizeBytes');
            AT_CheckWarning(rc);
            [rc,height] = AT_GetInt(hndl,'AOIheight');
            AT_CheckWarning(rc);
            [rc,width] = AT_GetInt(hndl,'AOIwidth');
            AT_CheckWarning(rc);
            [rc,stride] = AT_GetInt(hndl,'AOIstride');
            AT_CheckWarning(rc);

            %% 根据外部的Trigger拍照，常用，触发延迟20-50us

            totalswitch=0;
            app.Lamp.Color=[1,1,0];
            %相机初始化，配置参数
            %%
            [rc] = AT_InitialiseLibrary();%获取库
            AT_CheckWarning(rc);
            [rc,hndl] = AT_Open(0);
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera external trigger mode']);
            % 设置相机参数

            AT_SetEnumString(hndl,'PixelReadoutRate','Normal');%读出率，normal 200MHz
            AT_SetBool(hndl,'Overlap',1);%overapmode 1
            if app.GlobalShutterButton.Value == 1
                AT_SetEnumString(hndl,'ElectronicShutteringMode','Global');    %设置为global shutter
                disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera shutter mode set to be Global Shutter']);
            else
                AT_SetEnumString(hndl,'ElectronicShutteringMode','Rolling');    %设置为global shutter
                disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera shutter mode set to be Rolling Shutter']);
            end

            AT_SetEnumString(hndl,'SimplePreAmpGainControl','16-bit (low noise & high well capacity)'); %设置成16位
            AT_SetEnumString(hndl,'PixelEncoding','Mono16');
            AT_CheckWarning(rc);
            AT_SetInt(hndl,'AOIHeight',app.YRangeEditField.Value);    %设置高度
            AT_SetInt(hndl,'AOIWidth',app.XRangeEditField.Value);    %设置宽度
            try
                AT_SetInt(hndl,'AOITop',app.Center_top.Value-floor(app.YRangeEditField.Value/2));    %设置上边界
                AT_SetInt(hndl,'AOILeft',app.Center_left.Value-floor(app.XRangeEditField.Value/2)); %设置左边界
            catch
                disp('Center Settings Error');
            end
            % 获取相片大小，便于创建对应图片矩阵
            AT_SetFloat(hndl,'ExposureTime',app.ExposureTime.Value/1000);%设置曝光时间
            [rc,imagesize] = AT_GetInt(hndl,'imagesizeBytes');
            AT_CheckWarning(rc);
            [rc,height] = AT_GetInt(hndl,'AOIheight');
            AT_CheckWarning(rc);
            [rc,width] = AT_GetInt(hndl,'AOIwidth');
            AT_CheckWarning(rc);
            [rc,stride] = AT_GetInt(hndl,'AOIstride');
            AT_CheckWarning(rc);
            AT_SetFloat(hndl,'ExternalTriggerDelay',0);
            AT_SetEnumString(hndl,'CycleMode','Continuous');
            AT_SetEnumString(hndl,'TriggerMode','External');
            [rc,real_exposure]=AT_GetFloat(hndl,'ExposureTime');%真实曝光时间
            AT_CheckWarning(rc);
            app.ExposureTime.Value=real_exposure*1000;
            app.ExposureTime.ValueDisplayFormat='%.1f ms';
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera exposure time set to be ',num2str(real_exposure*1000),'ms']);
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera initialized']);

            %% 等待触发
            while(app.switchstatus==1)

                % 触发拍照并存储
                if totalswitch==1
                    % 如果counter卡ctr1获得触发，totalswitch 被置为1，相机处于等待状态，外部触发会触发采集

                    [rc] = AT_QueueBuffer(hndl,imagesize);
                    AT_CheckWarning(rc);
                    disp(' ');
                    disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Waiting for trigger']);
                    [rc] = AT_Command(hndl,'AcquisitionStart'); %这个命令让采集开始
                    %AT_CheckWarning(rc);

                    imagenumber = 1; %为了给每张图片一个名字，这里先给个初始值


                    while(app.switchstatus==1 && totalswitch==1)
                        if imagenumber>totalnumber
                            break   %达到设置的照片数后，会停止拍照
                        end
                        app.Lamp.Color=[0,1,0]; %green 代表接受触发中
                        buf2 = zeros(width,height);%创建一个空buffer，用来将相机的图片收过

                        %h=imagesc(buf2); %在matlab里显示图片
                        filename=[app.camera_path.Value,num2str(imagenumber),'.tif',];%将照片存储到相机图像存储路径
                        AT_QueueBuffer(hndl,imagesize);
                        try
                            [~,buf] = AT_WaitBuffer(hndl,20000); %在Timeout时间里等相机送过来的图片，如果超出这个t.imeout时间还没有图片，则自动报错退出
                        catch
                            disp('20s内无照片');
                            break;
                        end
                        [~,buf2] = AT_ConvertMono16ToMatrix(buf,height,width,stride);%将Mono16图片变成个矩阵
                        if(imagenumber==1)
                            app.maxpixel=max(max(buf2));
                            %app.MaxPixelTextArea.Value=num2str(app.maxpixel);
                            %为了第一张显示不报错
                        end
                        if(imagenumber==2 && atomphoto>1)
                            app.maxpixel=max(max(buf2));
                            app.MaxPixelTextArea.Value=num2str(app.maxpixel);
                            %获取第二张照片的最大像素值，为了归一化显示原始照片
                        end


                        if imagenumber<=atomphoto
                            eval(['app.showimage_raw(buf2,','app.image_',num2str(imagenumber),');']);%将原始照片显示在对应的窗口中
                        end
                        if imagenumber==atomphoto+1
                            app.showimage_raw(buf2,app.image_28);
                        end
                        if imagenumber==totalnumber
                            app.showimage_raw(buf2,app.image_29);
                        end
                        %set(h,'CData',buf2);   %将matlab显示的图片数据刷新，下一步是刷新图片
                        %drawnow;   %刷新图片
                        imwrite(buf2, filename);%把图片保存到本地
                        imagenumber = imagenumber+1;

                    end

                    app.Lamp.Color=[0,1,0];
                    totalswitch=0;

                end

                %拍照结束，数据处理
                if dataprocess==1

                    disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Start data processing...']);
                    %app.output.Value='正在计算中……';
                    %dir='E:\Matlab文件\Absorption Imaging\0\'; %调试用，一般不运行

                    xmin=app.XminEditField.Value;
                    xmax=app.XmaxEditField.Value;
                    ymin=app.YminEditField.Value;
                    ymax=app.YmaxEditField.Value;
                    dir=camera_origin_dir;%移动前数据的存储路径


                    numberofatoms_output(1:9)=0;
                    noiseOD_output(1:9)=0;
                    countperpixel_output(1:9)=0;
                    radius_output(1:9)=0;
                    ODsum_output(1:9)=0;
                    noiseFR_output(1:9)=0;
                    SNRGain_output(1:9)=0;
                    ODsum_mod_output(1:9)=0;


                    % 成像比例设置
                    if app.Image_ratio.Value == "1:1"
                        image_ratio=1;%1:1成像
                    elseif app.Image_ratio.Value == "1:2.5 (TOP)"
                        image_ratio=(1/2.5)^2;%放大成像2.5
                    elseif app.Image_ratio.Value == "1.67:1 (MOT)"
                        image_ratio=(1.67)^2;
                    elseif app.Image_ratio.Value == "2.33:1 (SC)"
                        image_ratio=(2.33)^2;
                    end


                    for ODnumber=1:atomphoto

                        [OD,noise_OD,~,~,~,~,~,countperpixel]=Optical_Density(dir,ODnumber,atomphoto,lightphoto,ymin,ymax,xmin,xmax);
                        %计算OD
                        OD_raw=OD;
                        [M,N]=size(OD);

                        OD(xmin-1:xmax+1,ymin-1)=1000;
                        OD(xmin-1:xmax+1,ymax+1)=1000;
                        OD(xmin-1,ymin-1:ymax+1)=1000;
                        OD(xmax+1,ymin-1:ymax+1)=1000;
                        %标示出区域ROI
                        eval(['app.showimage_OD(OD,app.image_',num2str(ODnumber+9),')']);%显示OD计算结果

                        mask=zeros(M,N);
                        antimask=ones(M,N);
                        mask(xmin:xmax,ymin:ymax)=1;
                        antimask(xmin:xmax,ymin:ymax)=0;
                        masked_OD=mask.*OD_raw;
                        background_OD=antimask.*OD_raw;
                        scale_masked_OD=max(max(masked_OD));
                        %imageposition=[1000,300,512,512];

                        if app.FringeRemovalSwitch.Value == "On" && ODnumber>1
                            [OD_FR,noise_FR,SNRGain]=FringeReduction(dir,ODnumber,lightphoto,atomphoto,xmin,ymin,xmax,ymax);
                            eval(['app.showimage_OD(OD_FR,app.image_',num2str(ODnumber+18),')']);
                            OD_FR=real(OD_FR);
                            noise_FR=real(noise_FR);
                            SNRGain=real(SNRGain);
                            masked_OD=mask.*OD_FR;
                            background_OD=antimask.*OD_FR;
                        elseif app.FringeRemovalSwitch.Value == "Off"

                            noise_FR=NaN;
                            SNRGain=NaN;
                        elseif app.FringeRemovalSwitch.Value == "On" && ODnumber==1
                            OD_FR=OD;
                            app.showimage_OD(OD_FR,app.image_19);
                            noise_FR=NaN;
                            SNRGain=NaN;
                        end
                        ODsum_mod=sum(masked_OD(:))-sum(background_OD(:))/(M*N-(xmax-xmin)*(ymax-ymin))*(xmax-xmin)*(ymax-ymin);


                        %OD 数据高斯二维拟合
                        if app.Fit2DSwitch.Value == "On"

                            [App,cxpp,cypp,sxpp,sypp,sitapp,offsetpp,~,image_Fit]=Gauss2DFit(masked_OD,1);
                            %h2=figure(2);
                            %set(h2,'Visible','on');
                            %set(h,'position',imageposition);
                            %colormap jet;
                            %set(gca,'Clim',[-0.1,scale])
                            app.showimage_fit(image_Fit,app.image_30);


                            %saveas(image_Fit,[dir,'\OD_2D_fit.bmp'],'bmp');
                            radius=((sxpp^2+sypp^2)/2)^(1/2)*(6.5/1000000)*1000;
                            %NumberofAtoms=App*2*pi*(sxpp*sypp)*(5.3/1000000).^2/(76)*10.^(15);
                            NumberofAtoms=App*2*pi*(sxpp*sypp)*(6.5/1000000).^2/(76)*10.^(15)*image_ratio;
                            OD_max=App;

                        elseif app.Fit2DSwitch.Value == "Off"

                            NumberofAtoms=sum(masked_OD(:))*(6.5/1000000).^2/(76)*10.^(15)*image_ratio;
                            radius=NaN;
                        end


                        numberofatoms_output(ODnumber)=NumberofAtoms;
                        noiseOD_output(ODnumber)=noise_OD;
                        countperpixel_output(ODnumber)=countperpixel;
                        radius_output(ODnumber)=radius;
                        ODsum_output(ODnumber)=sum(masked_OD(:));
                        noiseFR_output(ODnumber)=noise_FR;
                        SNRGain_output(ODnumber)=SNRGain;
                        ODsum_mod_output(ODnumber)=ODsum_mod;
                    end
                    %% 数据结果显示在表格中

                    app.UITable.Data={'Number of Atoms',numberofatoms_output(1),numberofatoms_output(2),numberofatoms_output(3),numberofatoms_output(4),numberofatoms_output(5),numberofatoms_output(6),numberofatoms_output(7),numberofatoms_output(8),numberofatoms_output(9); ...
                        'OD_sum_mod',ODsum_mod_output(1),ODsum_mod_output(2),ODsum_mod_output(3),ODsum_mod_output(4),ODsum_mod_output(5),ODsum_mod_output(6),ODsum_mod_output(7),ODsum_mod_output(8),ODsum_mod_output(9); ...
                        'OD sum',ODsum_output(1),ODsum_output(2),ODsum_output(3),ODsum_output(4),ODsum_output(5),ODsum_output(6),ODsum_output(7),ODsum_output(8),ODsum_output(9); ...
                        'Noise OD',noiseOD_output(1),noiseOD_output(2),noiseOD_output(3),noiseOD_output(4),noiseOD_output(5),noiseOD_output(6),noiseOD_output(7),noiseOD_output(8),noiseOD_output(9); ...
                        'Count per pixel',countperpixel_output(1),countperpixel_output(2),countperpixel_output(3),countperpixel_output(4),countperpixel_output(5),countperpixel_output(6),countperpixel_output(7),countperpixel_output(8),countperpixel_output(9); ...
                        'Radius',radius_output(1),radius_output(2),radius_output(3),radius_output(4),radius_output(5),radius_output(6),radius_output(7),radius_output(8),radius_output(9); ...
                        'SNR Gain',SNRGain_output(1),SNRGain_output(2),SNRGain_output(3),SNRGain_output(4),SNRGain_output(5),SNRGain_output(6),SNRGain_output(7),SNRGain_output(8),SNRGain_output(9); ...
                        'Noise_FR',noiseFR_output(1),noiseFR_output(2),noiseFR_output(3),noiseFR_output(4),noiseFR_output(5),noiseFR_output(6),noiseFR_output(7),noiseFR_output(8),noiseFR_output(9); ...
                        };
                    %%

                    %生成输出文件
                    disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Data processing complete']);


                    dataprocess=0;

                    %移动文件
                    identitystr=['Abs-',int2str(Pulse_number_list(2)+app.AdditionEditField.Value)];%这里设定的是目标文件夹的序号，默认从软件PulseSoftware运行后从1开始计数
                    mkdir(process_dir,identitystr);%创建目标文件夹
                    destination_path=[process_dir,identitystr,'\'];%目标文件夹路径设为变量，方便调用
                    status=[0,0,0];
                    for imagenumber=1:totalnumber
                        %disp("Ctr0端口触发了");
                        filename=[camera_origin_dir,num2str(imagenumber,'%d'),'.tif'];%照片原本的位置和名称
                        status(imagenumber)=movefile(filename,destination_path);%复制照片到目标文件夹，status表示复制是否成功

                    end
                    if status(1)==1 && status(2)==1 && status(3)==1
                        disp([datestr(now,'yyyy-mm-dd HH:MM:SS'),' Success to move to ',destination_path]);
                        %break;
                    else
                        disp([datestr(now,'yyyy-mm-dd HH:MM:SS'),' Fail to move to ',destination_path]);
                    end
                    imagenumber=1;

                    %存储相关数据并生成输出文件
                    save([destination_path,'\OD_raw.mat'],'OD_raw');
                    if app.FringeRemovalSwitch.Value == "On"
                        save([destination_path,'\OD_FR.mat'],'OD_FR');
                    end

                    if app.SaveFigSwitch.Value == "Yes"
                        h1=figure('Visible','on');
                        colormap jet;
                        imshow(OD_raw,'Colormap',jet);
                        set(gca,'Clim',[-0.1,app.scale.Value]);
                        savefig([destination_path,'\OD_raw.fig']);

                        imshow(OD,'Colormap',jet);
                        set(gca,'Clim',[-0.1,app.scale.Value]);
                        savefig([destination_path,'\OD_with_ROI.fig']);

                        if app.FringeRemovalSwitch.Value=="On"
                            imshow(OD_FR,'Colormap',jet);
                            set(gca,'Clim',[-0.1,app.scale.Value]);
                            savefig([destination_path,'\OD_FR.fig']);
                        end

                        if app.Fit2DSwitch.Value == "On"
                            imshow(image_Fit,'Colormap',jet);
                            set(gca,'Clim',[-0.1,app.scale.Value]);
                            savefig([destination_path,'\OD_fit_2D.fig']);
                        end
                        try
                            close(h1);
                        catch
                        end
                    end
                    %%上传至influxdb
                    if app.FringeRemovalSwitch == "On"
                        datatowrite=['ExpData,location=HFNL,source=artemis ',...
                            'ODsum1_mod=',num2str(ODsum_mod_output(2)),...
                            ',ODsum2_mod=',num2str(ODsum_mod_output(3)),...
                            ',ODsum3_mod=',num2str(ODsum_mod_output(4)),...
                            ',ODsum4_mod=',num2str(ODsum_mod_output(5)),...
                            ',ODsum1=',num2str(ODsum_output(2)),...
                            ',ODsum2=',num2str(ODsum_output(3)),...
                            ',ODsum3=',num2str(ODsum_output(4)),...
                            ',ODsum4=',num2str(ODsum_output(5)),...
                            ',NoAtom1=',num2str(ODsum_output(2)),...
                            ',NoAtom2=',num2str(ODsum_output(3)),...
                            ',NoAtom3=',num2str(ODsum_output(4)),...
                            ',NoAtom4=',num2str(ODsum_output(5)),...
                            ',Noise1=',num2str(noiseOD_output(2)),...
                            ',Noise2=',num2str(noiseOD_output(3)),...
                            ',Noise3=',num2str(noiseOD_output(4)),...
                            ',Noise4=',num2str(noiseOD_output(5)),...
                            ',NoiseFR1=',num2str(noiseFR_output(2)),...
                            ',NoiseFR2=',num2str(noiseFR_output(3)),...
                            ',NoiseFR3=',num2str(noiseFR_output(4)),...
                            ',NoiseFR4=',num2str(noiseFR_output(5)),...
                            ',PhotonCount1=',num2str(countperpixel_output(2)),...
                            ',PhotonCount2=',num2str(countperpixel_output(3)),...
                            ',PhotonCount3=',num2str(countperpixel_output(4)),...
                            ',PhotonCount4=',num2str(countperpixel_output(5))];
                    else
                        datatowrite=['ExpData,location=HFNL,source=artemis ',...
                            'ODsum1_mod=',num2str(ODsum_mod_output(2)),...
                            ',ODsum2_mod=',num2str(ODsum_mod_output(3)),...
                            ',ODsum3_mod=',num2str(ODsum_mod_output(4)),...
                            ',ODsum4_mod=',num2str(ODsum_mod_output(5)),...
                            ',ODsum1=',num2str(ODsum_output(2)),...
                            ',ODsum2=',num2str(ODsum_output(3)),...
                            ',ODsum3=',num2str(ODsum_output(4)),...
                            ',ODsum4=',num2str(ODsum_output(5)),...
                            ',NoAtom1=',num2str(ODsum_output(2)),...
                            ',NoAtom2=',num2str(ODsum_output(3)),...
                            ',NoAtom3=',num2str(ODsum_output(4)),...
                            ',NoAtom4=',num2str(ODsum_output(5)),...
                            ',Noise1=',num2str(noiseOD_output(2)),...
                            ',Noise2=',num2str(noiseOD_output(3)),...
                            ',Noise3=',num2str(noiseOD_output(4)),...
                            ',Noise4=',num2str(noiseOD_output(5)),...
                            ',PhotonCount1=',num2str(countperpixel_output(2)),...
                            ',PhotonCount2=',num2str(countperpixel_output(3)),...
                            ',PhotonCount3=',num2str(countperpixel_output(4)),...
                            ',PhotonCount4=',num2str(countperpixel_output(5))];
                    end
                    try
                        py.influxDB.writedata(py.str(datatowrite));
                        disp([datestr(now,'yyyy-mm-dd HH:MM:SS'),' Success to upload to influxDB']);
                    catch ME
                        disp([datestr(now,'yyyy-mm-dd HH:MM:SS'),' Fail to upload to influxDB']);
                        disp([datestr(now,'yyyy-mm-dd HH:MM:SS'),ME.identifier]);
                        disp([datestr(now,'yyyy-mm-dd HH:MM:SS'),ME.message]);
                    end


                    % 保存处理后的数据
                    outputfile=fopen([destination_path,'\Output','.txt'],'w');
                    for ODnumber=1:atomphoto
                        fprintf(outputfile,'%f ',numberofatoms_output(ODnumber));
                        fprintf(outputfile,'%f ',ODsum_mod_output(ODnumber));
                        fprintf(outputfile,'%f ',countperpixel_output(ODnumber));
                        fprintf(outputfile,'%f ',radius_output(ODnumber));
                        fprintf(outputfile,'%f ',ODsum_output(ODnumber));
                        fprintf(outputfile,'%f ',noiseFR_output(ODnumber));
                        fprintf(outputfile,'%f ',SNRGain_output(ODnumber));


                    end
                    fclose(outputfile);

                    % 保存时间文件
                    timefile=fopen([destination_path,'\time','.txt'],'w');
                    fprintf(timefile,'%s',datestr(now,'yyyy-mm-dd HH:MM:SS'));
                    fclose(timefile);


                    % 保存相机设置，字符型
                    setupfile=fopen([destination_path,'\Settings','.txt'],'w');
                    fprintf(setupfile,'%s',['Y range: ',num2str(height),'; ',...
                        'X range: ',num2str(width),'; ', ...
                        'Y center: ',num2str(app.Center_top.Value),'; ', ...
                        'X center: ',num2str(app.Center_left.Value),'; ', ...
                        'xmin: ',num2str(xmin),'; ', ...
                        'xmax: ',num2str(xmax),'; ', ...
                        'ymin: ',num2str(ymin),'; ', ...
                        'ymax: ',num2str(ymax),'; ', ...
                        'Exposure time: ',num2str(app.ExposureTime.Value), ...
                        'Atom number: ',num2str(atomphoto), ...
                        'Light number: ',num2str(lightphoto), ...
                        ]);
                    fclose(setupfile);

                    % 保存相机设置，数据型，可直接读取
                    settingsfile=fopen([destination_path,'\Camera_Settings','.txt'],'w');
                    fprintf(settingsfile,'%f %f %f %f %f %f %f %f %f',app.YRangeEditField.Value,app.XRangeEditField.Value,app.Center_top.Value, ...
                        app.Center_left.Value,app.XminEditField.Value,app.XmaxEditField.Value, ...
                        app.YminEditField.Value,app.YmaxEditField.Value,app.ExposureTime.Value);
                    fclose(settingsfile);


                    disp(' ');
                end

                pause(0.01);
            end

            %% 断开相机连接
            disp(' ');
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Acquisition complete - trigger mode']);
            [rc] = AT_Command(hndl,'AcquisitionStop');
            %AT_CheckWarning(rc);
            [rc] = AT_Flush(hndl);
            AT_CheckWarning(rc);
            [rc] = AT_Close(hndl);
            AT_CheckWarning(rc);
            [rc] = AT_FinaliseLibrary();
            AT_CheckWarning(rc);
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS '),'Camera shutdown']);
            disp(' ');
            app.Lamp.Color=[1,0,0];
            %%


            return;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.switchstatus=0;
            delete(app);
            clear all
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            app.switchstatus=0;
            app.Start.Text = '确定';
            app.Start.BackgroundColor=[0.96 0.96 0.96];
            app.Lamp.Color=[1,0,0];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            settingsdir=app.LoadSettingsdirEditField.Value;
            settingsfile=fopen(settingsdir,'r');
            settings=textscan(settingsfile,'%f %f %f %f %f %f %f %f %f');
            app.YRangeEditField.Value=settings{1};
            app.XRangeEditField.Value=settings{2};
            app.Center_top.Value=settings{3};
            app.Center_left.Value=settings{4};
            app.XminEditField.Value=settings{5};
            app.XmaxEditField.Value=settings{6};
            app.YminEditField.Value=settings{7};
            app.YmaxEditField.Value=settings{8};
            app.ExposureTime.Value=settings{9};
            fclose(settingsfile);
            disp('Settings Loaded');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            setupfile=fopen(app.SaveSettingsdirEditField.Value,'w');
            fprintf(setupfile,'%f %f %f %f %f %f %f %f %f',app.YRangeEditField.Value,app.XRangeEditField.Value,app.Center_top.Value, ...
                app.Center_left.Value,app.XminEditField.Value,app.XmaxEditField.Value, ...
                app.YminEditField.Value,app.YmaxEditField.Value,app.ExposureTime.Value);

            fclose(setupfile);
            disp('Settings Saved');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.image_1 = uiaxes(app.UIFigure);
            zlabel(app.image_1, 'Z')
            app.image_1.XLimitMethod = 'tight';
            app.image_1.YLimitMethod = 'tight';
            app.image_1.ZLimitMethod = 'tight';
            app.image_1.XTick = [];
            app.image_1.XTickLabel = '';
            app.image_1.YTick = [];
            app.image_1.YTickLabel = '';
            app.image_2 = uiaxes(app.UIFigure);
            zlabel(app.image_2, 'Z')
            app.image_2.XLimitMethod = 'tight';
            app.image_2.YLimitMethod = 'tight';
            app.image_2.ZLimitMethod = 'tight';
            app.image_2.XTick = [];
            app.image_2.XTickLabel = '';
            app.image_2.YTick = [];
            app.image_2.YTickLabel = '';
            app.image_3 = uiaxes(app.UIFigure);
            zlabel(app.image_3, 'Z')
            app.image_3.XLimitMethod = 'tight';
            app.image_3.YLimitMethod = 'tight';
            app.image_3.ZLimitMethod = 'tight';
            app.image_3.XTick = [];
            app.image_3.XTickLabel = '';
            app.image_3.YTick = [];
            app.image_3.YTickLabel = '';
            app.image_4 = uiaxes(app.UIFigure);
            zlabel(app.image_4, 'Z')
            app.image_4.XLimitMethod = 'tight';
            app.image_4.YLimitMethod = 'tight';
            app.image_4.ZLimitMethod = 'tight';
            app.image_4.XTick = [];
            app.image_4.XTickLabel = '';
            app.image_4.YTick = [];
            app.image_4.YTickLabel = '';
            app.image_5 = uiaxes(app.UIFigure);
            zlabel(app.image_5, 'Z')
            app.image_5.XLimitMethod = 'tight';
            app.image_5.YLimitMethod = 'tight';
            app.image_5.ZLimitMethod = 'tight';
            app.image_5.XTick = [];
            app.image_5.XTickLabel = '';
            app.image_5.YTick = [];
            app.image_5.YTickLabel = '';
            app.image_6 = uiaxes(app.UIFigure);
            zlabel(app.image_6, 'Z')
            app.image_6.XTick = [];
            app.image_6.XTickLabel = '';
            app.image_6.YTick = [];
            app.image_6.YTickLabel = '';
            app.image_7 = uiaxes(app.UIFigure);
            zlabel(app.image_7, 'Z')
            app.image_7.XTick = [];
            app.image_7.XTickLabel = '';
            app.image_7.YTick = [];
            app.image_7.YTickLabel = '';
            app.image_8 = uiaxes(app.UIFigure);
            zlabel(app.image_8, 'Z')
            app.image_8.XTick = [];
            app.image_8.XTickLabel = '';
            app.image_8.YTick = [];
            app.image_8.YTickLabel = '';
            app.image_9 = uiaxes(app.UIFigure);
            zlabel(app.image_9, 'Z')
            app.image_9.XTick = [];
            app.image_9.XTickLabel = '';
            app.image_9.YTick = [];
            app.image_9.YTickLabel = '';
            app.image_10 = uiaxes(app.UIFigure);
            zlabel(app.image_10, 'Z')
            app.image_10.XLimitMethod = 'tight';
            app.image_10.YLimitMethod = 'tight';
            app.image_10.ZLimitMethod = 'tight';
            app.image_10.XTick = [];
            app.image_10.XTickLabel = '';
            app.image_10.YTick = [];
            app.image_10.YTickLabel = '';
            app.image_11 = uiaxes(app.UIFigure);
            zlabel(app.image_11, 'Z')
            app.image_11.XLimitMethod = 'tight';
            app.image_11.YLimitMethod = 'tight';
            app.image_11.ZLimitMethod = 'tight';
            app.image_11.XTick = [];
            app.image_11.XTickLabel = '';
            app.image_11.YTick = [];
            app.image_11.YTickLabel = '';
            app.image_12 = uiaxes(app.UIFigure);
            zlabel(app.image_12, 'Z')
            app.image_12.XLimitMethod = 'tight';
            app.image_12.YLimitMethod = 'tight';
            app.image_12.ZLimitMethod = 'tight';
            app.image_12.XTick = [];
            app.image_12.XTickLabel = '';
            app.image_12.YTick = [];
            app.image_12.YTickLabel = '';
            app.image_13 = uiaxes(app.UIFigure);
            zlabel(app.image_13, 'Z')
            app.image_13.XLimitMethod = 'tight';
            app.image_13.YLimitMethod = 'tight';
            app.image_13.ZLimitMethod = 'tight';
            app.image_13.XTick = [];
            app.image_13.XTickLabel = '';
            app.image_13.YTick = [];
            app.image_13.YTickLabel = '';
            app.image_14 = uiaxes(app.UIFigure);
            zlabel(app.image_14, 'Z')
            app.image_14.XLimitMethod = 'tight';
            app.image_14.YLimitMethod = 'tight';
            app.image_14.ZLimitMethod = 'tight';
            app.image_14.XTick = [];
            app.image_14.XTickLabel = '';
            app.image_14.YTick = [];
            app.image_14.YTickLabel = '';
            app.image_15 = uiaxes(app.UIFigure);
            zlabel(app.image_15, 'Z')
            app.image_15.XTick = [];
            app.image_15.XTickLabel = '';
            app.image_15.YTick = [];
            app.image_15.YTickLabel = '';
            app.image_16 = uiaxes(app.UIFigure);
            zlabel(app.image_16, 'Z')
            app.image_16.XTick = [];
            app.image_16.XTickLabel = '';
            app.image_16.YTick = [];
            app.image_16.YTickLabel = '';
            app.image_17 = uiaxes(app.UIFigure);
            zlabel(app.image_17, 'Z')
            app.image_17.XTick = [];
            app.image_17.XTickLabel = '';
            app.image_17.YTick = [];
            app.image_17.YTickLabel = '';
            app.image_18 = uiaxes(app.UIFigure);
            zlabel(app.image_18, 'Z')
            app.image_18.XTick = [];
            app.image_18.XTickLabel = '';
            app.image_18.YTick = [];
            app.image_18.YTickLabel = '';
            app.image_19 = uiaxes(app.UIFigure);
            zlabel(app.image_19, 'Z')
            app.image_19.XLimitMethod = 'tight';
            app.image_19.YLimitMethod = 'tight';
            app.image_19.ZLimitMethod = 'tight';
            app.image_19.XTick = [];
            app.image_19.XTickLabel = '';
            app.image_19.YTick = [];
            app.image_19.YTickLabel = '';
            app.image_20 = uiaxes(app.UIFigure);
            zlabel(app.image_20, 'Z')
            app.image_20.XLimitMethod = 'tight';
            app.image_20.YLimitMethod = 'tight';
            app.image_20.ZLimitMethod = 'tight';
            app.image_20.XTick = [];
            app.image_20.XTickLabel = '';
            app.image_20.YTick = [];
            app.image_20.YTickLabel = '';
            app.image_21 = uiaxes(app.UIFigure);
            zlabel(app.image_21, 'Z')
            app.image_21.XLimitMethod = 'tight';
            app.image_21.YLimitMethod = 'tight';
            app.image_21.ZLimitMethod = 'tight';
            app.image_21.XTick = [];
            app.image_21.XTickLabel = '';
            app.image_21.YTick = [];
            app.image_21.YTickLabel = '';
            app.image_22 = uiaxes(app.UIFigure);
            zlabel(app.image_22, 'Z')
            app.image_22.XLimitMethod = 'tight';
            app.image_22.YLimitMethod = 'tight';
            app.image_22.ZLimitMethod = 'tight';
            app.image_22.XTick = [];
            app.image_22.XTickLabel = '';
            app.image_22.YTick = [];
            app.image_22.YTickLabel = '';
            app.image_23 = uiaxes(app.UIFigure);
            zlabel(app.image_23, 'Z')
            app.image_23.XLimitMethod = 'tight';
            app.image_23.YLimitMethod = 'tight';
            app.image_23.ZLimitMethod = 'tight';
            app.image_23.XTick = [];
            app.image_23.XTickLabel = '';
            app.image_23.YTick = [];
            app.image_23.YTickLabel = '';
            app.image_24 = uiaxes(app.UIFigure);
            zlabel(app.image_24, 'Z')
            app.image_24.XTick = [];
            app.image_24.XTickLabel = '';
            app.image_24.YTick = [];
            app.image_24.YTickLabel = '';
            app.image_25 = uiaxes(app.UIFigure);
            zlabel(app.image_25, 'Z')
            app.image_25.XTick = [];
            app.image_25.XTickLabel = '';
            app.image_25.YTick = [];
            app.image_25.YTickLabel = '';
            app.image_26 = uiaxes(app.UIFigure);
            zlabel(app.image_26, 'Z')
            app.image_26.XTick = [];
            app.image_26.XTickLabel = '';
            app.image_26.YTick = [];
            app.image_26.YTickLabel = '';
            app.image_27 = uiaxes(app.UIFigure);
            zlabel(app.image_27, 'Z')
            app.image_27.XTick = [];
            app.image_27.XTickLabel = '';
            app.image_27.YTick = [];
            app.image_27.YTickLabel = '';
            app.image_28 = uiaxes(app.UIFigure);
            zlabel(app.image_28, 'Z')
            app.image_28.XLimitMethod = 'tight';
            app.image_28.YLimitMethod = 'tight';
            app.image_28.ZLimitMethod = 'tight';
            app.image_28.XTick = [];
            app.image_28.XTickLabel = '';
            app.image_28.YTick = [];
            app.image_28.YTickLabel = '';
            app.image_29 = uiaxes(app.UIFigure);
            zlabel(app.image_29, 'Z')
            app.image_29.XLimitMethod = 'tight';
            app.image_29.YLimitMethod = 'tight';
            app.image_29.ZLimitMethod = 'tight';
            app.image_29.XTick = [];
            app.image_29.XTickLabel = '';
            app.image_29.YTick = [];
            app.image_29.YTickLabel = '';
            app.image_30 = uiaxes(app.UIFigure);
            zlabel(app.image_30, 'Z')
            app.image_30.XLimitMethod = 'tight';
            app.image_30.YLimitMethod = 'tight';
            app.image_30.ZLimitMethod = 'tight';
            app.image_30.XTick = [];
            app.image_30.XTickLabel = '';
            app.image_30.YTick = [];
            app.image_30.YTickLabel = '';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = '确定';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'推荐在开始运行前先打开PulseSoftware；'; '完成模式、路径等设置后点击确定即可开始；'; '若要切换模式，请先点击“停止”，再更改模式；'; '退出时请先停止！'; ''; '目前只有外部触发模式是会保存照片到电脑的。'; ''; '路径填写时请加上最后的反斜杠\'};
            app.ExposureLabel = uilabel(app.UIFigure);
            app.ExposureLabel.Text = 'Exposure';
            app.ExposureTime = uieditfield(app.UIFigure, 'numeric');
            app.ExposureTime.Limits = [0 Inf];
            app.ExposureTime.ValueDisplayFormat = '%.1f ms';
            app.ExposureTime.Value = 8;
            app.YRangeEditFieldLabel = uilabel(app.UIFigure);
            app.YRangeEditFieldLabel.Text = 'Y Range';
            app.YRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.YRangeEditField.Limits = [1 2160];
            app.YRangeEditField.Value = 200;
            app.XRangeEditFieldLabel = uilabel(app.UIFigure);
            app.XRangeEditFieldLabel.Text = 'X Range';
            app.XRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.XRangeEditField.Limits = [1 2560];
            app.XRangeEditField.Value = 200;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '相机图像存储路径';
            app.camera_path = uieditfield(app.UIFigure, 'text');
            app.camera_path.Value = 'E:\Data\';
            app.stop = uibutton(app.UIFigure, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Text = '停止';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '相机状态';
            app.Lamp = uilamp(app.UIFigure);
            app.AtomNumberLabel = uilabel(app.UIFigure);
            app.AtomNumberLabel.Text = 'Atom Number';
            app.atomphotoediting = uieditfield(app.UIFigure, 'numeric');
            app.atomphotoediting.Value = 3;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '图像处理文件路径';
            app.process_path = uieditfield(app.UIFigure, 'text');
            app.process_path.Value = 'E:\Data\';
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Value = 130;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField.Value = 70;
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Value = 130;
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.Value = 70;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'红：关闭'; '黄：待命'; '绿：采样中'};
            app.XCenterLabel = uilabel(app.UIFigure);
            app.XCenterLabel.Text = 'X Center';
            app.Center_left = uieditfield(app.UIFigure, 'numeric');
            app.Center_left.Limits = [1 2560];
            app.Center_left.Value = 1300;
            app.YCenterLabel = uilabel(app.UIFigure);
            app.YCenterLabel.Text = 'Y Center';
            app.Center_top = uieditfield(app.UIFigure, 'numeric');
            app.Center_top.Limits = [1 2160];
            app.Center_top.Value = 800;
            app.MaxPixelTextAreaLabel = uilabel(app.UIFigure);
            app.MaxPixelTextAreaLabel.Text = 'Max Pixel';
            app.MaxPixelTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'DATA1'; 'DATA2'; 'DATA3'; 'DATA4'; 'DATA5'; 'DATA6'; 'DATA7'; 'DATA8'; 'DATA9'};
            app.UITable.RowName = {};
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ROILabel_2 = uilabel(app.UIFigure);
            app.ROILabel_2.Text = 'ROI';
            app.Fit2DSwitchLabel = uilabel(app.UIFigure);
            app.Fit2DSwitchLabel.Text = 'Fit 2D';
            app.Fit2DSwitch = uiswitch(app.UIFigure, 'slider');
            app.ODscaleEditFieldLabel = uilabel(app.UIFigure);
            app.ODscaleEditFieldLabel.Text = 'OD scale';
            app.scale = uieditfield(app.UIFigure, 'numeric');
            app.scale.Value = 0.5;
            app.AdditionEditFieldLabel = uilabel(app.UIFigure);
            app.AdditionEditFieldLabel.Text = 'Addition';
            app.AdditionEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '图片1.png';
            app.LoadSettingsdirEditFieldLabel = uilabel(app.UIFigure);
            app.LoadSettingsdirEditFieldLabel.Text = 'Load Settings dir';
            app.LoadSettingsdirEditField = uieditfield(app.UIFigure, 'text');
            app.LoadSettingsdirEditField.Value = 'E:\Data\Settings\LoadSettings1.txt';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveSettingsdirEditFieldLabel = uilabel(app.UIFigure);
            app.SaveSettingsdirEditFieldLabel.Text = 'Save Settings dir';
            app.SaveSettingsdirEditField = uieditfield(app.UIFigure, 'text');
            app.SaveSettingsdirEditField.Value = 'E:\Data\Settings\LoadSettings1.txt';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FringeRemovalSwitchLabel = uilabel(app.UIFigure);
            app.FringeRemovalSwitchLabel.Text = 'Fringe Removal';
            app.FringeRemovalSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightNumberLabel = uilabel(app.UIFigure);
            app.LightNumberLabel.Text = 'Light Number';
            app.lightphotoediting = uieditfield(app.UIFigure, 'numeric');
            app.lightphotoediting.Value = 25;
            app.ShutterModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ShutterModeButtonGroup.Title = 'Shutter Mode';
            app.RollingShutterButton = uiradiobutton(app.ShutterModeButtonGroup);
            app.RollingShutterButton.Text = 'Rolling Shutter';
            app.RollingShutterButton.Value = true;
            app.GlobalShutterButton = uiradiobutton(app.ShutterModeButtonGroup);
            app.GlobalShutterButton.Text = 'Global Shutter';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '成像比例';
            app.Image_ratio = uidropdown(app.UIFigure);
            app.Image_ratio.Items = {'1:1', '1:2.5 (TOP)', '2.33:1 (SC)', '1.67:1 (MOT)'};
            app.Image_ratio.Value = '1:2.5 (TOP)';
            app.SaveFigSwitchLabel = uilabel(app.UIFigure);
            app.SaveFigSwitchLabel.Text = 'Save Fig';
            app.SaveFigSwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveFigSwitch.Items = {'No', 'Yes'};
            app.SaveFigSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraSoftware_20260323

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end