classdef PulseSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label_2                 matlab.ui.control.Label
        logpath                 matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        Title                   matlab.ui.control.TextArea
        ShowPulseSwitch         matlab.ui.control.Switch
        ShowPulseSwitchLabel    matlab.ui.control.Label
        Caption                 matlab.ui.control.TextArea
        StatusLamp              matlab.ui.control.Lamp
        OutputTextArea          matlab.ui.control.TextArea
        ConnectingdeviceButton  matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        switch_loop; % Description
        dev;
        dq;
        logfile;
        t;
    end
    
    methods (Access = public)


  end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          global imagenumber;
          global Pulse_number_list;
           app.StartButton.Text = 'Waiting for Pulse';
            app.StartButton.BackgroundColor=[0 1 0.5];
            app.ExitButton.BackgroundColor=[0.96,0.96,0.96];
            app.ExitButton.Text='Stop';
            n = [0 0 0 0];
            app.switch_loop = 1;
            app.OutputTextArea.Value = ["正在执行Pulse->Code","退出请点Exit"];
            fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS ') "正在执行Pulse->Code"]);
            Pulse_number_list_initial=read(app.dq,"OutputFormat","Matrix");
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS ') ,'正在执行Pulse->Code']);
            %这里是由于不管有没有点击start，counter卡都会不停计数，因此需要保证点击start后能知道counter卡数到多少了
     
      function pulse_execut(~,~,app)
          if app.switch_loop==0
              stop(app.t);
              delete(app.t);
               app.OutputTextArea.Value = ["程序执行结束","如需再次启动请点击Start"];
            fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS ') "程序执行结束"]);
            return;
          end
           
            Pulse_number_list = read(app.dq,"OutputFormat","Matrix")-Pulse_number_list_initial;
                if app.ShowPulseSwitch.Value == "On"
                    app.OutputTextArea.Value = ["正在执行Pulse->Code","退出请点Stop",int2str(Pulse_number_list)];
                end
                fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS ') '四个通道pulse数量为 ' int2str(Pulse_number_list)]);
                for i = 1:4
                    while n(i) < Pulse_number_list(i)
                        n(i) = Pulse_number_list(i);
                        %imagenumber=n(1);
                        Code4EveryPulse(i); % 这个文件里是每个pulse执行的代码
                        
                    end
                end
        end
            app.t=timer;%这里利用timer循环避开matlab while循环无法并行的问题
        
            app.t.StartDelay=0;
            app.t.ExecutionMode='fixedRate';
            app.t.Period=0.05;%循环的周期
            app.t.TimerFcn={@pulse_execut,app};
            start(app.t);
           
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            global Pulse_number_list
            app.ExitButton.BackgroundColor=[1,0,0];
            app.ExitButton.Text='Stop';
            app.StartButton.BackgroundColor=[0.96,0.96,0.96];
            app.StartButton.Text='Start';
            app.switch_loop=0;
            disp([datestr(now, 'yyyy-mm-dd HH:MM:SS ') '四个通道pulse数量为 ' int2str(Pulse_number_list)]);
            disp('');
        end

        % Button pushed function: ConnectingdeviceButton
        function ConnectingdeviceButtonPushed(app, event)

            
            try
                app.OutputTextArea.Value = ["正在创建日志文件……"];
                app.logfile = fopen([app.logpath.Value{1} datestr(now, 'dd~mmm~yyyy HH-MM-SS') '.log'],'w');
                fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS '),"正在创建日志文件……"]);
                app.OutputTextArea.Value = ["正在创建日志文件……完成";"正在连接设备及通道……"];
                fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS ') "正在连接设备……"]);
                try
                    app.dev = daqlist; %设备列表
                    app.dq = daq("ni"); %选择设备采集的设备，设备此时是dq
                    addinput(app.dq,"Dev1","ctr0","EdgeCount"); %设置一个channel，用Dev1的ctr0来采集数据
                    addinput(app.dq,"Dev1","ctr1","EdgeCount"); %设置一个channel，用Dev1的ctr1来采集数据
                    addinput(app.dq,"Dev1","ctr2","EdgeCount"); %设置一个channel，用Dev1的ctr2来采集数据
                    addinput(app.dq,"Dev1","ctr3","EdgeCount"); %设置一个channel，用Dev1的ctr3来采集数据
                    app.OutputTextArea.Value = ["正在创建日志文件……完成";"正在连接设备及通道……完成"];
                    fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS ') "连接设备及四个通道ctr0-3完成"]);
                    app.StatusLamp.Color = [0.00,1.00,0.00];
                catch
                    app.OutputTextArea.Value = ["正在创建日志文件……完成";"正在连接设备及通道……";"设备及通道连接失败"];
                    fprintf(app.logfile, '%s', [datestr(now, 'dd-mmm-yyyy HH:MM:SS ') "设备及四个通道ctr0-3连接失败"]);
                    app.StatusLamp.Color = [1.00,0.00,0.00];
                end
            catch
                app.OutputTextArea.Value = ["正在创建日志文件……";"输入日志文件路径有误"];
                app.StatusLamp.Color = [1.00,0.00,0.00];
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            app.switch_loop = 0;
            pause(0.001);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ConnectingdeviceButton = uibutton(app.UIFigure, 'push');
            app.ConnectingdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectingdeviceButtonPushed, true);
            app.ConnectingdeviceButton.Text = 'Connecting device';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.StatusLamp = uilamp(app.UIFigure);
            app.Caption = uitextarea(app.UIFigure);
            app.Caption.Value = {'程序说明：'; '1. 调用Ctr0-3四个counter口，每收到一个脉冲就执行一段代码'; '2. 得到脉冲所执行的代码请编辑"Code4EveryPulse.m"'; ''; '程序使用：Connect -> Start -> Exit（关闭该窗口前先点Exit！）'; 'Show Pulse如果开了，可以显示四个通道目前的Pulse数量'; ''; '注：'; '1. 日志文件路径是可以被修改的'; '2. 再次提醒，关闭该窗口前先点Exit！！'};
            app.ShowPulseSwitchLabel = uilabel(app.UIFigure);
            app.ShowPulseSwitchLabel.Text = 'Show Pulse';
            app.ShowPulseSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowPulseSwitch.Value = 'On';
            app.Title = uitextarea(app.UIFigure);
            app.Title.Value = {'脉冲触发软件'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '日志文件路径';
            app.logpath = uitextarea(app.UIFigure);
            app.logpath.Value = {'E:\Data\NI 6612 Counter\'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '连接状态';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PulseSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end